classdef adip < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        EnsureAllBandsAreUploadedLabel  matlab.ui.control.Label
        RedBandLabel                    matlab.ui.control.Label
        BlueBandLabel                   matlab.ui.control.Label
        UploadBand2Button               matlab.ui.control.Button
        UploadBand3Button               matlab.ui.control.Button
        UploadBand4Button               matlab.ui.control.Button
        UploadBand5Button               matlab.ui.control.Button
        UploadBand6Button               matlab.ui.control.Button
        UploadBand7Button               matlab.ui.control.Button
        ApplyAlgorithmButton            matlab.ui.control.Button
        ExtractBoundariesButton         matlab.ui.control.Button
        SWIRBandLabel                   matlab.ui.control.Label
        MustafaSohailMurtazaAliKhokharLabel  matlab.ui.control.Label
        HDImageBandLabel                matlab.ui.control.Label
        NIRBandLabel                    matlab.ui.control.Label
        GreenBandLabel                  matlab.ui.control.Label
        CS446AppliedDigitalImageProcessingLabel  matlab.ui.control.Label
        WaterBodyExtractionMonitoringProgramLabel  matlab.ui.control.Label
        OutputImageLabel                matlab.ui.control.Label
        InputImageLabel                 matlab.ui.control.Label
        InputImage                      matlab.ui.control.UIAxes
        OutputImage                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Band2 % Red
        Band3 % Green
        Band4 % Blue
        Band5 % NIR
        Band6 %SWIR
        Band7 % HD
        boundaries %Boundary
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadBand2Button
        function UploadButton2Pushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif;*.tif;*.bmp','All Image Files';'*.*','All Files'}, 'Select Green Band Image');
            if isequal(file,0) || isequal(path,0)
                disp('User pressed cancel');
            else
                imageFile = fullfile(path, file);
                app.Band2 = imread(imageFile); % Store the Red band image
            end
        end

        % Button pushed function: UploadBand3Button
        function UploadButton3Pushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif;*.tif;*.bmp','All Image Files';'*.*','All Files'}, 'Select Green Band Image');
            if isequal(file,0) || isequal(path,0)
                disp('User pressed cancel');
            else
                imageFile = fullfile(path, file);
                app.Band3 = imread(imageFile); % Store the Green band image
            end
        end

        % Button pushed function: UploadBand4Button
        function UploadButton4Pushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif;*.tif;*.bmp','All Image Files';'*.*','All Files'}, 'Select Green Band Image');
            if isequal(file,0) || isequal(path,0)
                disp('User pressed cancel');
            else
                imageFile = fullfile(path, file);
                app.Band4 = imread(imageFile); % Store the Blue band image
            end
        end

        % Button pushed function: UploadBand5Button
        function UploadButton5Pushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif;*.tif;*.bmp','All Image Files';'*.*','All Files'}, 'Select Green Band Image');
            if isequal(file,0) || isequal(path,0)
                disp('User pressed cancel');
            else
                imageFile = fullfile(path, file);
                app.Band5 = imread(imageFile); % Store the NIR band image
            end
        end

        % Button pushed function: UploadBand6Button
        function UploadButton6Pushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif;*.tif;*.bmp','All Image Files';'*.*','All Files'}, 'Select Green Band Image');
            if isequal(file,0) || isequal(path,0)
                disp('User pressed cancel');
            else
                imageFile = fullfile(path, file);
                app.Band6 = imread(imageFile); % Store the SWIR band image
            end
        end

        % Button pushed function: UploadBand7Button
        function UploadButton7Pushed(app, event)
            % Open file dialog to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.gif;*.tif;*.bmp','All Image Files';'*.*','All Files'}, 'Select an image');
            % Check if the user selected a file or clicked cancel
            if isequal(file,0) || isequal(path,0)
                disp('User pressed cancel');
            else
                % Full path to the selected image
                imageFile = fullfile(path, file);
                % Read the image
                app.Band7 = imread(imageFile);
                % Display the image in the UIAxes component
                imshow(app.Band7, 'Parent', app.InputImage);
                % Adjust the aspect ratio and axis off
                axis(app.InputImage, 'image');
                axis(app.InputImage, 'off');
            end
        end

        % Button pushed function: ApplyAlgorithmButton
        function AlgorithmOneButtonPushed(app, event)
            % Check if all bands have been uploaded
            if isempty(app.Band2) 
                uialert(app.UIFigure, 'Please upload Band 2 before applying the algorithm.', 'Band 2 Missing');
                return;
            elseif isempty(app.Band3)
                uialert(app.UIFigure, 'Please upload Band 3 before applying the algorithm.', 'Band 3 Missing');
                return;
            elseif isempty(app.Band4)
                uialert(app.UIFigure, 'Please upload Band 4 before applying the algorithm.', 'Band 4 Missing');
                return;
            elseif isempty(app.Band5)
                uialert(app.UIFigure, 'Please upload Band 5 before applying the algorithm.', 'Band 5 Missing');
                return;
            elseif isempty(app.Band6)
                uialert(app.UIFigure, 'Please upload Band 6 before applying the algorithm.', 'Band 6 Missing');
                return;
            elseif isempty(app.Band7)
                uialert(app.UIFigure, 'Please upload Band 7 before applying the algorithm.', 'Band 7 Missing');
                return;
            end
    
            % If all bands are uploaded, proceed with the algorithm
            outputImage = algorithmOne(app.Band2, app.Band3, app.Band4, app.Band5, app.Band6, app.Band7);
    
            % Display the output image in the OutputImage UIAxes
            imshow(outputImage, 'Parent', app.OutputImage);
        end

        % Button pushed function: ExtractBoundariesButton
        function ExtractBoundariesButtonPushed(app, event)
            % Check if all bands have been uploaded
            if isempty(app.Band2) 
                uialert(app.UIFigure, 'Please upload Band 2 before applying the algorithm.', 'Band 2 Missing');
                return;
            elseif isempty(app.Band3)
                uialert(app.UIFigure, 'Please upload Band 3 before applying the algorithm.', 'Band 3 Missing');
                return;
            elseif isempty(app.Band4)
                uialert(app.UIFigure, 'Please upload Band 4 before applying the algorithm.', 'Band 4 Missing');
                return;
            elseif isempty(app.Band5)
                uialert(app.UIFigure, 'Please upload Band 5 before applying the algorithm.', 'Band 5 Missing');
                return;
            elseif isempty(app.Band6)
                uialert(app.UIFigure, 'Please upload Band 6 before applying the algorithm.', 'Band 6 Missing');
                return;
            elseif isempty(app.Band7)
                uialert(app.UIFigure, 'Please upload Band 7 before applying the algorithm.', 'Band 7 Missing');
                return;
            end
    
            % If all bands are uploaded, proceed with the algorithm
            outputImage = algorithmTwo(app.Band4,app.Band5);
            app.boundaries = outputImage;
            imshow(algorithmOne(app.Band2, app.Band3, app.Band4, app.Band5, app.Band6, app.Band7), 'Parent', app.OutputImage);
            hold (app.OutputImage,'on');
            for k=1:size(outputImage)
                b = outputImage{k};
                [H,W] = size(b);
               if(H>1000)
                plot(app.OutputImage,b(:,2),b(:,1),'w','LineWidth',1,'Color','green'); %displaying bwboundaries boundary
               end
            end
            hold (app.OutputImage,'off');
            % Display the output image in the OutputImage UIAxes
%             imshow(outputImage, 'Parent', app.OutputImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OutputImage = uiaxes(app.GridLayout);
            zlabel(app.OutputImage, 'Z')
            app.OutputImage.XTick = [];
            app.OutputImage.YAxisLocation = 'origin';
            app.OutputImage.YTick = [];
            app.InputImage = uiaxes(app.GridLayout);
            zlabel(app.InputImage, 'Z')
            app.InputImage.XTick = [];
            app.InputImage.YAxisLocation = 'origin';
            app.InputImage.YTick = [];
            app.InputImageLabel = uilabel(app.GridLayout);
            app.InputImageLabel.Text = 'Input Image';
            app.OutputImageLabel = uilabel(app.GridLayout);
            app.OutputImageLabel.Text = 'Output Image';
            app.WaterBodyExtractionMonitoringProgramLabel = uilabel(app.GridLayout);
            app.WaterBodyExtractionMonitoringProgramLabel.Text = 'Water Body Extraction & Monitoring Program';
            app.CS446AppliedDigitalImageProcessingLabel = uilabel(app.GridLayout);
            app.CS446AppliedDigitalImageProcessingLabel.Text = 'CS 446 - Applied Digital Image Processing';
            app.GreenBandLabel = uilabel(app.GridLayout);
            app.GreenBandLabel.Text = 'Green Band';
            app.NIRBandLabel = uilabel(app.GridLayout);
            app.NIRBandLabel.Text = 'NIR Band';
            app.HDImageBandLabel = uilabel(app.GridLayout);
            app.HDImageBandLabel.Text = 'HD Image Band';
            app.MustafaSohailMurtazaAliKhokharLabel = uilabel(app.GridLayout);
            app.MustafaSohailMurtazaAliKhokharLabel.Text = 'Mustafa Sohail & Murtaza Ali Khokhar';
            app.SWIRBandLabel = uilabel(app.GridLayout);
            app.SWIRBandLabel.Text = 'SWIR Band';
            app.ExtractBoundariesButton = uibutton(app.GridLayout, 'push');
            app.ExtractBoundariesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractBoundariesButtonPushed, true);
            app.ExtractBoundariesButton.Text = 'Extract Boundaries';
            app.ApplyAlgorithmButton = uibutton(app.GridLayout, 'push');
            app.ApplyAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @AlgorithmOneButtonPushed, true);
            app.ApplyAlgorithmButton.Text = 'Apply Algorithm';
            app.UploadBand7Button = uibutton(app.GridLayout, 'push');
            app.UploadBand7Button.ButtonPushedFcn = createCallbackFcn(app, @UploadButton7Pushed, true);
            app.UploadBand7Button.Text = 'Upload Band 7';
            app.UploadBand6Button = uibutton(app.GridLayout, 'push');
            app.UploadBand6Button.ButtonPushedFcn = createCallbackFcn(app, @UploadButton6Pushed, true);
            app.UploadBand6Button.Text = 'Upload Band 6';
            app.UploadBand5Button = uibutton(app.GridLayout, 'push');
            app.UploadBand5Button.ButtonPushedFcn = createCallbackFcn(app, @UploadButton5Pushed, true);
            app.UploadBand5Button.Text = 'Upload Band 5';
            app.UploadBand4Button = uibutton(app.GridLayout, 'push');
            app.UploadBand4Button.ButtonPushedFcn = createCallbackFcn(app, @UploadButton4Pushed, true);
            app.UploadBand4Button.Text = 'Upload Band 4';
            app.UploadBand3Button = uibutton(app.GridLayout, 'push');
            app.UploadBand3Button.ButtonPushedFcn = createCallbackFcn(app, @UploadButton3Pushed, true);
            app.UploadBand3Button.Text = 'Upload Band 3';
            app.UploadBand2Button = uibutton(app.GridLayout, 'push');
            app.UploadBand2Button.ButtonPushedFcn = createCallbackFcn(app, @UploadButton2Pushed, true);
            app.UploadBand2Button.Text = 'Upload Band 2';
            app.BlueBandLabel = uilabel(app.GridLayout);
            app.BlueBandLabel.Text = 'Blue Band';
            app.RedBandLabel = uilabel(app.GridLayout);
            app.RedBandLabel.Text = 'Red Band';
            app.EnsureAllBandsAreUploadedLabel = uilabel(app.GridLayout);
            app.EnsureAllBandsAreUploadedLabel.Text = 'Ensure All Bands Are Uploaded!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adip

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end